classdef daq_webinar_apps_v0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        LigaButton                    matlab.ui.control.Button
        TempodeAquisioEditFieldLabel  matlab.ui.control.Label
        TempodeAquisioEditField       matlab.ui.control.NumericEditField
        UIAxes2                       matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LigaButton
        function LigaButtonPushed(app, event)
            d = daq("directsound");
            addinput(d,"Audio1",1,"Audio")
            [data,t] = read(d, seconds(app.TempodeAquisioEditField.Value), "OutputFormat","Matrix");
            plot(app.UIAxes,t,data)


% FFT

xfft = abs(fft(data));

% Avoid taking the log of 0.
index = find(xfft == 0);
xfft(index) = 1e-17;

mag = 20*log10(xfft);
mag = mag(1:floor(length(t)/2));
f = (0:length(mag)-1)*d.Rate/length(t);
f = f(:);%The results are given below.
plot(app.UIAxes2,f,mag)
            
        end

        % Value changed function: TempodeAquisioEditField
        function TempodeAquisioEditFieldValueChanged(app, event)
            value = app.TempodeAquisioEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sinal ')
            xlabel(app.UIAxes, 'Tempo')
            ylabel(app.UIAxes, 'Sinal')
            app.LigaButton = uibutton(app.UIFigure, 'push');
            app.LigaButton.ButtonPushedFcn = createCallbackFcn(app, @LigaButtonPushed, true);
            app.LigaButton.Text = 'Liga';
            app.TempodeAquisioEditFieldLabel = uilabel(app.UIFigure);
            app.TempodeAquisioEditFieldLabel.Text = {'Tempo de '; 'Aquisição'};
            app.TempodeAquisioEditField = uieditfield(app.UIFigure, 'numeric');
            app.TempodeAquisioEditField.ValueChangedFcn = createCallbackFcn(app, @TempodeAquisioEditFieldValueChanged, true);
            app.TempodeAquisioEditField.Value = 10;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'FFT')
            xlabel(app.UIAxes2, 'Magnitude(dB)')
            ylabel(app.UIAxes2, 'Frequencia(Hz)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = daq_webinar_apps_v0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end