classdef daq_webinar_apps_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        StartButton  matlab.ui.control.Button
        StopButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        DAQ                   % Handle to DAQ object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create a new data acquisition object
            d = daq("directsound"); 
            % Store data acquisition object handle in DAQ app property
            app.DAQ = d; 
            
            addinput(app.DAQ,"Audio1",1,"Audio")
            
             
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            start(app.DAQ,"Continuous")
            [data,t] = read(app.DAQ,seconds(10), "OutputFormat","Matrix");
            plot(app.UIAxes,t,data)
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.DAQ)
            delete(app.DAQ)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'sinal')
            xlabel(app.UIAxes, 'tempo')
            ylabel(app.UIAxes, 'Y')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = daq_webinar_apps_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end